classdef dspapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TextArea               matlab.ui.control.TextArea
        TextAreaLabel          matlab.ui.control.Label
        outlyerEditField       matlab.ui.control.NumericEditField
        outlyerEditFieldLabel  matlab.ui.control.Label
        Button                 matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        MVCfiles % read in mvc files
        maxMVC % Description
        desiredoutlineval
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            [app.MVCfiles,path] = uigetfile('*','MultiSelect','on');  %open multiple files
            kanaal = [3 4 5 1 2]; % op welk kanaal worden deze spieren ingelezen (kolom)
             for i = 1:length(app.MVCfiles) 
                 file = app.MVCfiles{i};
                 filenamematrix(:,i) = string(file) ;
             end
    for c = 1:5
        filename = filenamematrix(:,c);
        disp(filenamematrix(:,c));
        matrix = readmatrix(string(filename));
        matrix(:,1:2) = [];
        kanaalnummer = kanaal(c);
        %inlezen parameters
        linesOfFile = readlines(filename);
        headerline = linesOfFile(2); % header is on the second line
        headerline = convertStringsToChars(headerline); % needed for further use
        headerline(1:24) = []; %remove chars to be able to read in as jsonobject
        headerline = headerline(1:end-1); %remove chars to be able to read in as jsonobject
        headerobject = jsondecode(headerline);
        datemvc = headerobject.date;
        timemvc = headerobject.time;
        Fsmvc = headerobject.samplingRate;   %sampfreq
        resolutionmvc = headerobject.resolution;

        % verwijderen van outlines: verwijderen en vervangen door gemiddelde
        % waarden onder en boven.
        matrixzonderoutliers = filloutliers(matrix,'center','mean','ThresholdFactor', app.desiredoutlineval); 
        %matrix omzetten naar mv
        matrix = toMV(matrix,resolutionmvc,Fsmvc);
        % Maximum bepalen
        maximumValue = max(matrix,[],1); %selects max value of all columns, used for later selection
        app.maxMVC(kanaalnummer,1) = maximumValue(kanaalnummer); %matrix which stores max values of each column in correct 
        % order for future use assuming future use is:
        %kanaal 1 = M. Trapezius Descendens (links)
        %kanaal 2 = M. Trapezius Descendens (rechts)
        %kanaal 3 = M. Deltoïdeus Anterior (links)
        %kanaal 4 = M. Deltoïdeus Anterior (rechts)
        %kanaal 5 = M. Extensor Carpi Radialis (dominante kant)
    end

    disp(app.maxMVC)


        end

        % Callback function
        function DropDownValueChanged(app, event)
  
        end

        % Value changed function: outlyerEditField
        function outlyerEditFieldValueChanged(app, event)
            app.desiredoutlineval = app.outlyerEditField.Value;
            disp(app.desiredoutlineval);
            
        end

        % Callback function: TextArea
        function TextAreaValueChanging(app, event)

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            app.TextArea.Value = string(app.maxMVC);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.outlyerEditFieldLabel = uilabel(app.UIFigure);
            app.outlyerEditFieldLabel.Text = 'outlyer';
            app.outlyerEditField = uieditfield(app.UIFigure, 'numeric');
            app.outlyerEditField.ValueChangedFcn = createCallbackFcn(app, @outlyerEditFieldValueChanged, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dspapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end